classdef FlightCockpit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightInstrumentsFlightDataPlaybackUIFigure  matlab.ui.Figure
        FlightCockpitDataDisplayLabel  matlab.ui.control.Label
        Time000secSlider               matlab.ui.control.Slider
        Time000secSliderLabel          matlab.ui.control.Label
        ClimbIndicator                 Aero.ui.control.ClimbIndicator
        HeadingIndicator               Aero.ui.control.HeadingIndicator
        TurnCoordinator                Aero.ui.control.TurnCoordinator
        Altimeter                      Aero.ui.control.Altimeter
        ArtificialHorizon              Aero.ui.control.ArtificialHorizon
        AirspeedIndicator              Aero.ui.control.AirspeedIndicator
        Image                          matlab.ui.control.Image
    end

   
    properties (Access = public) 
        simdata % Saved flight data [time X Y Z phi theta psi]
        animObj % Aero.Animation object
        output  % The output states of the GTM Model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% ------------------ Initializing the Upset Condition ------------------
            %run('SteepSpiral.m');           % To Simulate Steep Spiral Condition
            run('OscillatorySpin.m');       % To Simulate Oscillatory Spin Condition

            %% About
            %
            % Author:     Omar Mourad
            % Email:      <mailto: st181901@stud.uni-stuttgart.de>
            % Created:    28.03.2024 
            
            %% Loading Upset Flight Data
            app.output = y;
            % Load simulated GTM Upset flight data
            f2m = 0.3048;
            Data = [t dist_lat*f2m dist_lon*f2m alt*f2m app.output(:,16) app.output(:,17) app.output(:,18)];
            % Data = [time (secs), X-Distance (m), Y-Distance (m), Z-Distance (m), Altitude (m), Phi (rad), Theta (rad), Psi (rad)]
       
            yaw = Data(:,7);
            yaw(yaw<0) = yaw(yaw<0)+2*pi; % unwrap yaw angles
            Data(:,7) = yaw;
            app.simdata = Data;
            
            %% Creating a 3D Animation
            % Create animation object to visualize aircraft flight dynamics corresponding with saved data over time 
            app.animObj = Aero.Animation;
            app.animObj.createBody('pa24-250_orange.ac','Ac3d'); % Piper PA-24 Comanche geometry
            app.animObj.Bodies{1}.TimeseriesSourceType = 'Array6DoF'; % [time X Y Z phi theta psi]
            app.animObj.Bodies{1}.TimeSeriesSource = app.simdata;
            app.animObj.Camera.PositionFcn = @staticCameraPosition;
            app.animObj.Figure.Position = [app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(1)+625 app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(2) app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(3) app.FlightInstrumentsFlightDataPlaybackUIFigure.Position(4)];
            app.animObj.updateBodies(app.simdata(1,1)); % Initialize animation window at t=0
            app.animObj.updateCamera(app.simdata(1,1));
            app.animObj.show();
        end

        % Value changing function: Time000secSlider
        function Time000secSliderValueChanging(app, event)
            
            % Display current time in slider component
            t = event.Value;
            app.Time000secSliderLabel.Text = sprintf('Time: %.1f sec', t);           
            
            % Find corresponding time data entry
            k = find(app.simdata(:,1)<=t);
            k = k(end);
            
            % Altimeter Altitude
            app.Altimeter.Altitude = convlength(-app.simdata(k,4), 'm', 'ft');

            % Heading Indicator
            app.HeadingIndicator.Heading = convang(app.simdata(k,7),'rad','deg');

            % Artificial Horizon
            app.ArtificialHorizon.Roll = convang(app.simdata(k,5),'rad','deg');
            app.ArtificialHorizon.Pitch = convang(app.simdata(k,6),'rad','deg');
            
            if k>1
                % AirSpeed Indicator 
                app.AirspeedIndicator.Airspeed = app.output(k,1);

                kts2mps = 0.514444;
                Vel_z = (app.output(k,1)*kts2mps)*sind(app.output(k,3))*cosd(app.output(k,4));
                
                % Climb Indicator
                app.ClimbIndicator.ClimbRate = convvel(-Vel_z,'m/s','ft/min');

                % Turn Coordinator
                roll_rate = app.output(k,10);
                yaw_rate = app.output(k,12);
                bank_angle = app.simdata(k,5);
                app.TurnCoordinator.Turn = convangvel(roll_rate*sin(bank_angle) + yaw_rate*cos(bank_angle),'rad/s','deg/s');
                app.TurnCoordinator.Slip = 1/(2*pi)*convang(atan(yaw_rate*app.output(k,1)/9.81)-app.simdata(k,5),'rad','deg');
            else
                % time = 0
                app.ClimbIndicator.ClimbRate = 0;
                app.AirspeedIndicator.Airspeed = 0;
                app.TurnCoordinator.Slip = 0;
                app.TurnCoordinator.Turn = 0;
            end
            
            %% Update animation window display
            app.animObj.updateBodies(app.simdata(k,1));
            app.animObj.updateCamera(app.simdata(k,1));
            
        end

        % Close request function: 
        % FlightInstrumentsFlightDataPlaybackUIFigure
        function FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest(app, event)
            % Close animation figure with app
            delete(app.animObj);
            delete(app); 
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightInstrumentsFlightDataPlaybackUIFigure = uifigure('Visible', 'off');
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Name = 'Flight Instruments - Flight Data Playback';
            app.FlightInstrumentsFlightDataPlaybackUIFigure.CloseRequestFcn = createCallbackFcn(app, @FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest, true);
            app.Image = uiimage(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Image.ImageSource = 'appdesignerInstrumentPanel.png';
            app.AirspeedIndicator = uiaeroairspeed(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.AirspeedIndicator.Limits = [25 250];
            app.ArtificialHorizon = uiaerohorizon(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Altimeter = uiaeroaltimeter(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.TurnCoordinator = uiaeroturn(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.HeadingIndicator = uiaeroheading(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator = uiaeroclimb(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator.MaximumRate = 8000;
            app.Time000secSliderLabel = uilabel(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Time000secSliderLabel.Text = 'Time: 00.0 sec';
            app.Time000secSlider = uislider(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Time000secSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Time000secSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.Time000secSlider.ValueChangingFcn = createCallbackFcn(app, @Time000secSliderValueChanging, true);
            app.Time000secSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.FlightCockpitDataDisplayLabel = uilabel(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.FlightCockpitDataDisplayLabel.Text = 'Flight Cockpit Data Display';
            % Show the figure after all components are created
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightCockpit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightInstrumentsFlightDataPlaybackUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightInstrumentsFlightDataPlaybackUIFigure)
        end
    end
end